classdef Audiogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudiogramApp      matlab.ui.Figure
        LabelAudiogram    matlab.ui.control.Label
        ImageCelebrity    matlab.ui.control.Image
        ButtonNext        matlab.ui.control.Button
        ButtonPrevious    matlab.ui.control.Button
        ButtonPorownaj    matlab.ui.control.Button
        ButtonSlysze      matlab.ui.control.Button
        ButtonRozpocznij  matlab.ui.control.Button
        AxesAudiogram     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %kalibracja
            fs=48000;
            f=1000;
            t=0:1/fs:1;
            A=0.01; % domyslnie bylo 1
            y=A*sin(2*pi*f*t);
            Py=10*log10(sum(y.^2));
            coef=10.^(5/20);
            yy=y.*coef;
            Pyy=10*log10(sum(yy.^2));
            zz=[];
            for i=1:10
            z1=y(1,1:48000);
            z2=yy(1,1:48000);
            zz=[zz z1 z2];
            end
            %z4=zz/max(abs(zz));
            %plot(z4)
            %audiowrite('test.wav', zz, fs);
         
            
            sound(zz, fs); %dzwiek testowy
            h=questdlg('Kliknij, gdy będziesz słyszeć tylko jeden z dwóch tonów', ...
                'Kalibracja','OK','OK');
            switch h
              case 'OK'
                 %'OK' code here
                 clear("sound");
              case ''
                 %abort code
              otherwise
            end
            
            left=y(1,1:48000);
            left = [zeros(size(left)); left];
            left = [left left left];
            sound(left, 48000);
            
%             f = msgbox("Witam", "Kalibracja");
%             while (f == true)
%                 sound(zz, fs);
%             end
            %f = msgbox("Witam", "Kalibracja");
        end

        % Button pushed function: ButtonRozpocznij
        function CallbackRozpocznij(app, event)
            
            % Procedura wykonania audiogramu
            
            % Kanał lewy
            
            left=y(1,1:48000);
            left = [zeros(size(left)); left];
            left = [left left left];
            sound(left, 48000);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudiogramApp = uifigure('Visible', 'off');
            app.AudiogramApp.Name = 'MATLAB App';
            app.LabelAudiogram = uilabel(app.AudiogramApp);
            app.LabelAudiogram.Text = 'Audiogram';
            app.ImageCelebrity = uiimage(app.AudiogramApp);
            app.ButtonNext = uibutton(app.AudiogramApp, 'push');
            app.ButtonPrevious = uibutton(app.AudiogramApp, 'push');
            app.ButtonPorownaj = uibutton(app.AudiogramApp, 'push');
            app.ButtonPorownaj.Text = 'Porównaj';
            app.ButtonSlysze = uibutton(app.AudiogramApp, 'push');
            app.ButtonSlysze.Text = 'Słyszę';
            app.ButtonRozpocznij = uibutton(app.AudiogramApp, 'push');
            app.ButtonRozpocznij.ButtonPushedFcn = createCallbackFcn(app, @CallbackRozpocznij, true);
            app.ButtonRozpocznij.Text = 'Rozpocznij test';
            app.AxesAudiogram = uiaxes(app.AudiogramApp);
            title(app.AxesAudiogram, 'Title')
            xlabel(app.AxesAudiogram, 'Częstotliwość [Hz]')
            ylabel(app.AxesAudiogram, 'Ubytek słuchu [dB]')
            zlabel(app.AxesAudiogram, 'Z')
            % Show the figure after all components are created
            app.AudiogramApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audiogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudiogramApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudiogramApp)
        end
    end
end